classdef FleetManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RootGrid            matlab.ui.container.GridLayout
        TopBar              matlab.ui.container.Panel
        TopBarGrid          matlab.ui.container.GridLayout
        BrandPanel          matlab.ui.container.Panel
        BrandGrid           matlab.ui.container.GridLayout
        BrandIcon           matlab.ui.control.Label
        BrandTitle          matlab.ui.control.Label
        BrandSub            matlab.ui.control.Label
        VersionBadge        matlab.ui.control.Label
        RunStateBadge       matlab.ui.control.Label
        ReportButton        matlab.ui.control.Button
        MainGrid            matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        LeftGrid            matlab.ui.container.GridLayout
        LeftTabs            matlab.ui.container.TabGroup
        TabMap              matlab.ui.container.Tab
        MapTabGrid          matlab.ui.container.GridLayout
        PrimaryButton       matlab.ui.control.Button
        BtnClearRobots      matlab.ui.control.Button
        BtnPlaceRobots      matlab.ui.control.Button
        SeedField_2         matlab.ui.control.Spinner
        DensityLabel_2      matlab.ui.control.Label
        BtnRandomMap        matlab.ui.control.Button
        DensitySlider       matlab.ui.control.Slider
        DensityValue        matlab.ui.control.Label
        DensityLabel        matlab.ui.control.Label
        SeedField           matlab.ui.control.NumericEditField
        MapSizeSpinner      matlab.ui.control.Spinner
        SeedLabel           matlab.ui.control.Label
        MapSizeLabel        matlab.ui.control.Label
        MapTypeDropdown     matlab.ui.control.DropDown
        MapTypeLabel        matlab.ui.control.Label
        TabPlan             matlab.ui.container.Tab
        PlanTabGrid         matlab.ui.container.GridLayout
        GlobalPlannerLabel  matlab.ui.control.Label
        GlobalPlannerDrop   matlab.ui.control.DropDown
        ConflictLabel       matlab.ui.control.Label
        ConflictDrop        matlab.ui.control.DropDown
        HeuristicLabel      matlab.ui.control.Label
        HeuristicDrop       matlab.ui.control.DropDown
        InfoBox             matlab.ui.control.TextArea
        TabLayers           matlab.ui.container.Tab
        LayersTabGrid       matlab.ui.container.GridLayout
        LayersTitle         matlab.ui.control.Label
        ShowPlannedPath     matlab.ui.control.CheckBox
        ShowExecutedPath    matlab.ui.control.CheckBox
        ShowWaitForGraph    matlab.ui.control.CheckBox
        ShowGoals           matlab.ui.control.CheckBox
        ShowIDs             matlab.ui.control.CheckBox
        SecondaryRow        matlab.ui.container.GridLayout
        LegendGrid          matlab.ui.container.GridLayout
        LegendEngel         matlab.ui.control.Label
        LegendRobot         matlab.ui.control.Label
        LegendHedef         matlab.ui.control.Label
        LegendWait          matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        RightGrid           matlab.ui.container.GridLayout
        CardsRow            matlab.ui.container.GridLayout
        CardTimeGrid        matlab.ui.container.GridLayout
        CardTimeTitle       matlab.ui.control.Label
        CardTimeValue       matlab.ui.control.Label
        CardTime            matlab.ui.container.Panel
        CardDL              matlab.ui.container.Panel
        CardDLGrid          matlab.ui.container.GridLayout
        CardDLTitle         matlab.ui.control.Label
        CardDLValue         matlab.ui.control.Label
        CardDone            matlab.ui.container.Panel
        CardDoneGrid        matlab.ui.container.GridLayout
        CardDoneTitle       matlab.ui.control.Label
        CardDoneValue       matlab.ui.control.Label
        MapHost             matlab.ui.container.Panel
        MapAxes             matlab.ui.control.UIAxes
        BottomTabs          matlab.ui.container.TabGroup
        LogTab              matlab.ui.container.Tab
        LogGrid             matlab.ui.container.GridLayout
        LogArea             matlab.ui.control.TextArea
        BtnClearLog         matlab.ui.control.Button
        RobotsTab           matlab.ui.container.Tab
        RobotsGrid          matlab.ui.container.GridLayout
        RobotTable          matlab.ui.control.Table
    end

    properties (Access = private)
        C_BG      = [0.06 0.07 0.09]
        C_PANEL   = [0.09 0.10 0.13]
        C_CARD    = [0.12 0.14 0.18]
        C_INPUT   = [0.15 0.17 0.22]
        C_ACCENT  = [0.30 0.56 0.98]
        C_TEXT    = [0.95 0.96 0.98]
        C_DIM     = [0.55 0.58 0.65]
        C_OK      = [0.20 0.78 0.45]
        C_WARN    = [0.98 0.75 0.20]
        C_BAD     = [0.92 0.30 0.30]

        MapSize = 40
        ObstacleDensity = 0.20
        Seed = 42
        Map

        Robots = struct([])
        NumRobots = 10
        RobotColors
        ExecutedPaths

        IsRunning = false
        CurrentStep = 0
        DeadlockCount = 0
        CollisionCount = 0
        CollisionPairs
        CompletedCount = 0
        StartTime
    end

    methods (Access = private)

        function log(app, msg)
            t = datestr(now, 'HH:MM:SS');
            line = sprintf("[%s] %s", t, msg);
            if isempty(app.LogArea.Value)
                app.LogArea.Value = string(line);
            else
                app.LogArea.Value = [string(line); app.LogArea.Value];
            end
        end


        function count = countDeadEnds(app)
            n = app.MapSize;
            occMatrix = occupancyMatrix(app.Map);
            count = 0;

            for x = 2:n-1
                for y = 2:n-1
                    if occMatrix(y,x) == 0
                        neighbors = [occMatrix(y-1,x), occMatrix(y+1,x), ...
                            occMatrix(y,x-1), occMatrix(y,x+1)];
                        freeCount = sum(neighbors == 0);
                        if freeCount <= 1
                            count = count + 1;
                        end
                    end
                end
            end
        end

        function clearDeadEnds(app)
            n = app.MapSize;
            occMatrix = occupancyMatrix(app.Map);

            changed = true;
            while changed
                changed = false;
                for x = 2:n-1
                    for y = 2:n-1
                        if occMatrix(y,x) == 0
                            neighbors = [occMatrix(y-1,x), occMatrix(y+1,x), ...
                                occMatrix(y,x-1), occMatrix(y,x+1)];
                            freeCount = sum(neighbors == 0);
                            if freeCount <= 1
                                setOccupancy(app.Map, [x, y], 1);
                                occMatrix(y,x) = 1;
                                changed = true;
                            end
                        end
                    end
                end
            end
        end

        function renderMap(app)
            ax = app.MapAxes;
            cla(ax);
            hold(ax, 'on');

            if isempty(app.Map)
                % Harita yoksa burada cikiyorum.
                hold(ax, 'off');
                return;
            end

            n = app.MapSize;


            occMatrix = occupancyMatrix(app.Map);

            ax.Color = [0.05 0.06 0.08];

            [obsY, obsX] = find(occMatrix == 1);
            if ~isempty(obsX)
                patch(ax, [obsX-0.5, obsX+0.5, obsX+0.5, obsX-0.5]', ...
                    [obsY-0.5, obsY-0.5, obsY+0.5, obsY+0.5]', ...
                    [0.22 0.26 0.35], 'EdgeColor', 'none');
            end

            for i = 1:length(app.Robots)
                pos = app.Robots(i).currentPos;
                goalPos = app.Robots(i).goal;
                c = app.RobotColors(i,:);

                if app.ShowPlannedPath.Value

                    if isfield(app.Robots(i), 'fullPath') && ~isempty(app.Robots(i).fullPath)
                        plot(ax, app.Robots(i).fullPath(:,1), app.Robots(i).fullPath(:,2), ...
                            '--', 'Color', [c 0.5], 'LineWidth', 1.2);
                    elseif ~isempty(app.Robots(i).path)
                        plot(ax, app.Robots(i).path(:,1), app.Robots(i).path(:,2), ...
                            '--', 'Color', [c 0.5], 'LineWidth', 1.2);
                    end
                end

                if app.ShowExecutedPath.Value && isfield(app.Robots(i), 'history') && size(app.Robots(i).history, 1) > 1
                    plot(ax, app.Robots(i).history(:,1), app.Robots(i).history(:,2), ...
                        '-', 'Color', c, 'LineWidth', 2);
                end

                if app.ShowGoals.Value
                    plot(ax, goalPos(1), goalPos(2), 'd', 'MarkerSize', 6, ...
                        'MarkerFaceColor', c, 'MarkerEdgeColor', 'none');
                end

                markerSize = max(6, min(10, round(300 / app.MapSize)));

                if isfield(app.Robots(i), 'status')
                    status = app.Robots(i).status;
                else
                    status = 'Bekliyor';
                end

                if strcmp(status, 'tamamlandi')
                    edgeColor = app.C_OK;
                elseif isfield(app.Robots(i), 'waitingFor') && app.Robots(i).waitingFor > 0
                    edgeColor = app.C_WARN;
                else
                    edgeColor = [1 1 1];
                end

                plot(ax, pos(1), pos(2), 'o', 'MarkerSize', markerSize, ...
                    'MarkerFaceColor', c, 'MarkerEdgeColor', edgeColor, 'LineWidth', 1);

                if app.ShowIDs.Value
                    text(ax, pos(1), pos(2) + 1.2, num2str(i), ...
                        'Color', 'w', 'FontWeight', 'bold', 'FontSize', 8, ...
                        'HorizontalAlignment', 'center');
                end
            end

            if app.ShowWaitForGraph.Value && ~isempty(app.Robots)
                for i = 1:length(app.Robots)
                    if isfield(app.Robots(i), 'waitingFor') && app.Robots(i).waitingFor > 0
                        j = app.Robots(i).waitingFor;
                        if j <= length(app.Robots)
                            pos1 = app.Robots(i).currentPos;
                            pos2 = app.Robots(j).currentPos;
                            dx = pos2(1) - pos1(1);
                            dy = pos2(2) - pos1(2);
                            quiver(ax, pos1(1), pos1(2), dx*0.8, dy*0.8, 0, ...
                                'Color', app.C_OK, 'LineWidth', 4, 'MaxHeadSize', 0.2);
                        end
                    end
                end
            end

            axis(ax, 'equal');
            xlim(ax, [0.5, n+0.5]);
            ylim(ax, [0.5, n+0.5]);
            ax.XTick = []; ax.YTick = [];
            ax.XColor = 'none'; ax.YColor = 'none';
            hold(ax, 'off');
        end

        function updateRobotTable(app)
            if isempty(app.Robots)

                app.RobotTable.Data = {};
                return;
            end

            data = cell(length(app.Robots), 4);
            for i = 1:length(app.Robots)
                data{i,1} = i;
                data{i,2} = sprintf('[%d,%d]', app.Robots(i).currentPos(1), app.Robots(i).currentPos(2));
                data{i,3} = sprintf('[%d,%d]', app.Robots(i).goal(1), app.Robots(i).goal(2));
                data{i,4} = app.Robots(i).status;
            end
            app.RobotTable.Data = data;
        end

        function planPaths(app)
            plannerKey = app.getPlannerKey();
            app.log(sprintf('planPaths: %s', plannerKey));

            switch plannerKey
                case 'cbs'
                    app.planCBS();
                case 'prioritized'
                    app.planPrioritizedSpaceTime();
                case 'windowed'
                    app.planWindowedA(10);
                otherwise
                    app.planIndependentA();
            end
        end

        function plannerKey = getPlannerKey(app)
            val = app.GlobalPlannerDrop.Value;
            if strcmp(val, 'WHCA* (Fast)') || strcmp(val, 'Windowed A*')
                plannerKey = 'windowed';
            elseif strcmp(val, 'CBS (Multi-Agent)')
                plannerKey = 'cbs';
            elseif strcmp(val, 'Prioritized Planning')
                plannerKey = 'prioritized';
            else
                plannerKey = 'independent';
            end
        end

        function planIndependentA(app)
            occ = occupancyMatrix(app.Map);
            for i = 1:length(app.Robots)
                rota = app.gridAStar(app.Robots(i).start, app.Robots(i).goal, occ);
                if isempty(rota)
                    app.Robots(i).path = [];
                    app.Robots(i).fullPath = [];
                    app.Robots(i).status = 'yol yok';
                else
                    app.Robots(i).path = rota;
                    app.Robots(i).fullPath = [];
                    app.Robots(i).status = 'planlandi';
                end
            end
        end

        function planCBS(app)

            occ = occupancyMatrix(app.Map);
            nRob = length(app.Robots);


            for i = 1:nRob
                rota = app.gridAStar(app.Robots(i).start, app.Robots(i).goal, occ);
                if isempty(rota)
                    app.Robots(i).path = [];
                    app.Robots(i).fullPath = [];
                    app.Robots(i).status = 'yol yok';
                else
                    app.Robots(i).path = rota;
                    app.Robots(i).fullPath = [];
                    app.Robots(i).status = 'planlandi';
                end
            end

            % Adim 2: Cakisma tespiti ve cozumu basit CBS
            maxIter = 10;
            for iter = 1:maxIter
                % Rezervasyon tablosu olustur
                resTable = containers.Map('KeyType', 'char', 'ValueType', 'double');
                conflicts = [];
                firstConflict = [];
                conflictFound = false;

                for i = 1:nRob
                    pth = app.Robots(i).path;
                    if isempty(pth), continue; end
                    for t = 1:size(pth, 1)
                        key = app.resKey(pth(t, 1), pth(t, 2), t-1);
                        if isKey(resTable, key)
                            % Cakisma bulundu
                            % zaten asagida sadece conflicts(1,:) kullaniyoruz.
                            firstConflict = [resTable(key), i, t-1];
                            conflicts = [conflicts; firstConflict]; %#ok<AGROW>
                            conflictFound = true;
                            break;
                        else
                            resTable(key) = i;
                        end
                    end
                    if conflictFound
                        break; % nested loop'tan cik (hiz icin)
                    end
                end

                if ~conflictFound
                    break;
                end

                % ilk çakismayi çöz: yuksek ID'li robot yeniden planlar
                conf = firstConflict;
                rBot = max(conf(1), conf(2));

                % Diger robotlarin yollarini kisitlama olarak ekle
                constraintRes = containers.Map('KeyType', 'char', 'ValueType', 'double');
                for i = 1:nRob
                    if i == rBot, continue; end
                    pth = app.Robots(i).path;
                    if isempty(pth), continue; end
                    for t = 1:size(pth, 1)
                        constraintRes(app.resKey(pth(t, 1), pth(t, 2), t-1)) = i;
                    end
                    % Hedefte kalma
                    for hold = 1:5
                        constraintRes(app.resKey(pth(end, 1), pth(end, 2), size(pth,1)-1+hold)) = i;
                    end
                end

                % Yeniden planla
                newPath = app.spaceTimeAStar(app.Robots(rBot).start, app.Robots(rBot).goal, constraintRes, occ);
                if ~isempty(newPath)
                    app.Robots(rBot).path = newPath;
                    app.Robots(rBot).status = 'cbs-replan';
                end
            end
        end

        function planPrioritizedSpaceTime(app)
            % Prioritized: ID sirasina gore zamanli planlama
            resTable = containers.Map('KeyType', 'char', 'ValueType', 'double');
            occ = occupancyMatrix(app.Map);
            nRob = length(app.Robots);

            % ID sirasina gore planla (kucuk ID = yuksek oncelik)
            for i = 1:nRob
                rota = app.spaceTimeAStar(app.Robots(i).start, app.Robots(i).goal, resTable, occ);
                if isempty(rota)
                    app.Robots(i).path = [];
                    app.Robots(i).fullPath = [];
                    app.Robots(i).status = 'yol yok';
                    continue;
                end
                app.Robots(i).path = rota;
                app.Robots(i).fullPath = [];
                app.Robots(i).status = 'prioritized';
                % Rezervasyonlari ekle
                for t = 0:size(rota, 1)-1
                    resTable(app.resKey(rota(t+1, 1), rota(t+1, 2), t)) = i;
                end
                % Hedefte bekleme
                finalT = size(rota, 1) - 1;
                for hold = 1:5
                    resTable(app.resKey(rota(end, 1), rota(end, 2), finalT + hold)) = i;
                end
            end
        end

        function planWindowedA(app, pencere)
            occ = occupancyMatrix(app.Map);
            for i = 1:length(app.Robots)
                rotaTam = app.gridAStar(app.Robots(i).start, app.Robots(i).goal, occ);
                if isempty(rotaTam)
                    app.Robots(i).path = [];
                    app.Robots(i).fullPath = [];
                    app.Robots(i).status = 'yol yok';
                    continue;
                end

                app.Robots(i).fullPath = rotaTam;

                kesit = rotaTam(1:min(size(rotaTam, 1), pencere + 1), :);
                app.Robots(i).path = kesit;
                app.Robots(i).status = 'planlandi';
            end
        end

        function rota = gridAStar(app, start, goal, occ)
            n = app.MapSize;
            start = round(start); goal = round(goal);
            if occ(start(2), start(1)) == 1 || occ(goal(2), goal(1)) == 1
                rota = [];
                return;
            end

            dirs = [0 1; 0 -1; 1 0; -1 0];
            openList = [start(1), start(2), 0, app.calcHeuristic(start, goal)]; % x y g f
            cameFrom = containers.Map('KeyType', 'char', 'ValueType', 'char');
            gScore = containers.Map('KeyType', 'char', 'ValueType', 'double');
            startKey = sprintf('%d_%d', start(1), start(2));
            gScore(startKey) = 0;

            while ~isempty(openList)
                [~, minIdx] = min(openList(:, 4));
                current = openList(minIdx, :);
                openList(minIdx, :) = [];
                cx = current(1); cy = current(2); cg = current(3);

                if cx == goal(1) && cy == goal(2)
                    rota = [cx, cy];
                    key = sprintf('%d_%d', cx, cy);
                    while isKey(cameFrom, key)
                        parentKey = cameFrom(key);
                        parentVal = sscanf(parentKey, '%d_%d');
                        rota = [parentVal'; rota];
                        key = parentKey;
                    end
                    return;
                end

                for d = 1:4
                    nx = cx + dirs(d, 1);
                    ny = cy + dirs(d, 2);
                    if nx < 1 || nx > n || ny < 1 || ny > n, continue; end
                    if occ(ny, nx) == 1, continue; end

                    ng = cg + 1;
                    nKey = sprintf('%d_%d', nx, ny);
                    if ~isKey(gScore, nKey) || ng < gScore(nKey)
                        gScore(nKey) = ng;
                        cameFrom(nKey) = sprintf('%d_%d', cx, cy);
                        f = ng + app.calcHeuristic([nx, ny], goal);
                        openList(end+1, :) = [nx, ny, ng, f];
                    end
                end
            end
            rota = [];
        end

        function rota = spaceTimeAStar(app, start, goal, resTable, occ)
            n = app.MapSize;
            start = round(start); goal = round(goal);
            if occ(start(2), start(1)) == 1 || occ(goal(2), goal(1)) == 1
                rota = [];
                return;
            end

            dirs = [0 1; 0 -1; 1 0; -1 0; 0 0]; % son eleman bekleme
            openList = [start(1), start(2), 0, 0, app.calcHeuristic(start, goal)]; % x y t g f

            cameFrom = containers.Map('KeyType', 'char', 'ValueType', 'char');
            gScore = containers.Map('KeyType', 'char', 'ValueType', 'double');
            startKey = app.resKey(start(1), start(2), 0);
            gScore(startKey) = 0;
            maxT = max(120, n * 6);

            while ~isempty(openList)
                [~, minIdx] = min(openList(:, 5));
                current = openList(minIdx, :);
                openList(minIdx, :) = [];
                cx = current(1); cy = current(2); ct = current(3); cg = current(4);

                if cx == goal(1) && cy == goal(2)
                    endKey = app.resKey(cx, cy, ct);
                    rota = app.backtrackSpaceTime(cameFrom, endKey);
                    return;
                end
                if ct > maxT, continue; end

                for d = 1:size(dirs, 1)
                    nx = cx + dirs(d, 1);
                    ny = cy + dirs(d, 2);
                    nt = ct + 1;
                    if nx < 1 || nx > n || ny < 1 || ny > n, continue; end
                    if occ(ny, nx) == 1, continue; end
                    if isKey(resTable, app.resKey(nx, ny, nt)), continue; end

                    nKey = app.resKey(nx, ny, nt);
                    ng = cg + 1;
                    if ~isKey(gScore, nKey) || ng < gScore(nKey)
                        gScore(nKey) = ng;
                        cameFrom(nKey) = app.resKey(cx, cy, ct);
                        h = app.calcHeuristic([nx, ny], goal);
                        openList(end+1, :) = [nx, ny, nt, ng, ng + h];
                    end
                end
            end
            rota = [];
        end

        function rota = backtrackSpaceTime(~, cameFrom, endKey)
            rota = [];
            key = endKey;
            while true
                vals = sscanf(key, '%d_%d_%d');
                rota = [vals(1:2)'; rota];
                if ~isKey(cameFrom, key), break; end
                key = cameFrom(key);
            end
        end

        function rota = windowedAStar(app, start, goal, pencere)
            occ = occupancyMatrix(app.Map);
            rotaTam = app.gridAStar(start, goal, occ);
            if isempty(rotaTam)
                rota = [];
                return;
            end
            rota = rotaTam(1:min(size(rotaTam, 1), pencere + 1), :);
        end

        function k = resKey(~, x, y, t)
            k = sprintf('%d_%d_%d', round(x), round(y), round(t));
        end

        function d = manhattanDist(~, p1, p2)
            d = abs(p1(1) - p2(1)) + abs(p1(2) - p2(2));
        end

        function d = euclideanDist(~, p1, p2)
            d = sqrt((p1(1) - p2(1))^2 + (p1(2) - p2(2))^2);
        end

        function d = chebyshevDist(~, p1, p2)
            d = max(abs(p1(1) - p2(1)), abs(p1(2) - p2(2)));
        end

        function d = calcHeuristic(app, p1, p2)
            heuristic = app.HeuristicDrop.Value;
            switch heuristic
                case 'Manhattan'
                    d = app.manhattanDist(p1, p2);
                case 'Euclidean'
                    d = app.euclideanDist(p1, p2);
                case 'Chebyshev'
                    d = app.chebyshevDist(p1, p2);
                otherwise
                    d = app.manhattanDist(p1, p2);
            end
        end

        function runSimulation(app)
            app.log(sprintf('sim: seed=%d boyut=%d rob=%d', app.Seed, app.MapSize, length(app.Robots)));

            app.IsRunning = true;
            app.CurrentStep = 0;
            app.CompletedCount = 0;
            app.DeadlockCount = 0;
            app.CollisionCount = 0;
            app.CollisionPairs = containers.Map('KeyType','char','ValueType','double');
            app.StartTime = tic;
            plannerKey = app.getPlannerKey();
            nRob = length(app.Robots);

            for i = 1:nRob
                app.Robots(i).history = app.Robots(i).currentPos;
                app.Robots(i).pathIndex = 1;
                app.Robots(i).waitingFor = 0;
                app.Robots(i).waitCount = 0;
                if isequal(round(app.Robots(i).currentPos), round(app.Robots(i).goal))
                    app.Robots(i).status = 'tamamlandi';
                    app.CompletedCount = app.CompletedCount + 1;
                end
            end

            maxAdim = 800;
            cizimAralik = 4;

            while app.IsRunning && app.CurrentStep < maxAdim
                app.CurrentStep = app.CurrentStep + 1;

                if strcmp(plannerKey, 'windowed')
                    for i = 1:nRob
                        if strcmp(app.Robots(i).status, 'tamamlandi'), continue; end
                        pth = app.Robots(i).path;
                        idx = app.Robots(i).pathIndex;
                        if isempty(pth) || idx >= size(pth, 1)
                            seg = app.windowedAStar(app.Robots(i).currentPos, app.Robots(i).goal, 10);
                            if isempty(seg)
                                app.Robots(i).status = 'yol yok';
                                app.Robots(i).waitingFor = 0;
                                continue;
                            end
                            app.Robots(i).path = seg;
                            app.Robots(i).pathIndex = 1;
                        end
                    end
                end

                nextPos = cell(nRob, 1);
                for i = 1:nRob
                    if i > length(app.Robots)
                        break;
                    end
                    if strcmp(app.Robots(i).status, 'tamamlandi')
                        nextPos{i} = app.Robots(i).currentPos;
                        continue;
                    end
                    pth = app.Robots(i).path;
                    idx = app.Robots(i).pathIndex;
                    if isempty(pth) || idx >= size(pth, 1)
                        nextPos{i} = app.Robots(i).currentPos;
                    else
                        nextPos{i} = pth(idx + 1, 1:2);
                    end
                end

                for i = 1:nRob
                    if strcmp(app.Robots(i).status, 'tamamlandi')
                        continue;
                    end
                    pth = app.Robots(i).path;
                    idx = app.Robots(i).pathIndex;

                    if isempty(pth) || idx >= size(pth, 1)
                        if isequal(round(app.Robots(i).currentPos), round(app.Robots(i).goal)) && ~strcmp(app.Robots(i).status, 'tamamlandi')
                            app.Robots(i).status = 'tamamlandi';
                            app.Robots(i).waitingFor = 0;
                            app.CompletedCount = app.CompletedCount + 1;
                        else
                            app.Robots(i).status = 'yol yok';
                            app.Robots(i).waitingFor = 0;
                        end
                        continue;
                    end

                    nxt = nextPos{i};
                    prevWaitingFor = app.Robots(i).waitingFor;
                    [canMove, wFor, isCol] = app.checkConflict(i, nxt, nextPos, plannerKey);

                    % Collision tespiti
                    if isCol && wFor > 0 && prevWaitingFor ~= wFor
                        pairKey = sprintf('%d_%d', min(i,wFor), max(i,wFor));
                        if ~isKey(app.CollisionPairs, pairKey)
                            app.CollisionPairs(pairKey) = 1;
                            app.CollisionCount = app.CollisionCount + 1;
                        end
                    end

                    if canMove
                        app.Robots(i).currentPos = nxt;
                        app.Robots(i).pathIndex = idx + 1;
                        app.Robots(i).status = 'hareket';
                        app.Robots(i).waitingFor = 0;
                        app.Robots(i).waitCount = 0;
                        app.Robots(i).history = [app.Robots(i).history; nxt];
                        nextPos{i} = nxt;

                        if app.Robots(i).pathIndex >= size(pth, 1) && isequal(round(nxt), round(app.Robots(i).goal)) && ~strcmp(app.Robots(i).status, 'tamamlandi')
                            app.Robots(i).status = 'tamamlandi';
                            app.Robots(i).waitingFor = 0;
                            app.CompletedCount = app.CompletedCount + 1;
                        end
                    else
                        app.Robots(i).status = sprintf('bek(%d)', wFor);
                        app.Robots(i).waitingFor = wFor;
                        app.Robots(i).waitCount = app.Robots(i).waitCount + 1;
                    end
                end


                if mod(app.CurrentStep, 10) == 0
                    app.detectCyclicDeadlock();
                end

                tumBitti = true;
                takilan = 0;
                for i = 1:nRob
                    durum = app.Robots(i).status;
                    if strcmp(durum, 'tamamlandi')
                        continue;
                    elseif strcmp(durum, 'yol yok')
                        takilan = takilan + 1;
                    else
                        tumBitti = false;
                    end
                end

               
                if mod(app.CurrentStep, 20) == 0
                    gecenSure = toc(app.StartTime);
                    app.CardTimeValue.Text = sprintf('%.1fs', gecenSure);
                    app.CardDoneValue.Text = sprintf('%d/%d', app.CompletedCount, length(app.Robots));
                    app.CardDLValue.Text = sprintf('%d', app.DeadlockCount + app.CollisionCount);
                    app.renderMap();
                    drawnow limitrate;
                end

                if tumBitti
                    if takilan > 0
                        app.log(sprintf('takildi: %d', takilan));
                    end
                    break;
                end

                if app.CompletedCount >= nRob
                    break;
                end
            end

            gecenSure = toc(app.StartTime);
            app.CardTimeValue.Text = sprintf('%.1fs', gecenSure);
            app.CardDoneValue.Text = sprintf('%d/%d', app.CompletedCount, length(app.Robots));
            app.CardDLValue.Text = sprintf('%d', app.DeadlockCount + app.CollisionCount);

            app.renderMap();
            app.updateRobotTable();
            drawnow;

            app.IsRunning = false;
            app.RunStateBadge.Text = '● STOPPED';
            app.RunStateBadge.FontColor = app.C_DIM;
            app.log(sprintf('bitti: %d adim, %d/%d ok, dl:%d col:%d', ...
                app.CurrentStep, app.CompletedCount, length(app.Robots), ...
                app.DeadlockCount, app.CollisionCount));
        end

        function detectCyclicDeadlock(app)
            % Dongusal deadlock tespiti
            nRob = length(app.Robots);
            inCycle = false(1, nRob);

            for sr = 1:nRob
                if strcmp(app.Robots(sr).status, 'tamamlandi'), continue; end
                if app.Robots(sr).waitingFor == 0, continue; end
                if inCycle(sr), continue; end

                visited = zeros(1, nRob);
                cur = sr;
                adim = 1;
                cycleFound = false;
                cycleStart = 0;

                while cur > 0 && cur <= nRob && adim <= nRob
                    if visited(cur) > 0
                        cycleFound = true;
                        cycleStart = cur;
                        break;
                    end
                    visited(cur) = adim;
                    adim = adim + 1;
                    cur = app.Robots(cur).waitingFor;
                end

                if cycleFound
                    cycleRobs = [];
                    cur = cycleStart;
                    while true
                        cycleRobs = [cycleRobs, cur]; %#ok<AGROW>
                        inCycle(cur) = true;
                        cur = app.Robots(cur).waitingFor;
                        if cur == cycleStart, break; end
                    end

                    % En yuksek ID'li robotu sec (en dusuk oncelik)
                    rtr = max(cycleRobs);
                    app.Robots(rtr).waitCount = app.Robots(rtr).waitCount + 1;

                    % Sadece replan yapildiginda deadlock say
                    if app.Robots(rtr).waitCount >= 5
                        app.Robots(rtr).waitCount = 0;
                        app.DeadlockCount = app.DeadlockCount + 1;

                        % Yeniden planlama yap
                        np = app.gridAStar(app.Robots(rtr).currentPos, app.Robots(rtr).goal, occupancyMatrix(app.Map));
                        if ~isempty(np) && size(np, 1) > 1
                            app.Robots(rtr).path = np;
                            app.Robots(rtr).pathIndex = 1;
                            app.Robots(rtr).status = 'replan';
                            app.Robots(rtr).waitingFor = 0;
                        end
                    end
                end
            end
        end

        function [canMove, wFor, isCol] = checkConflict(app, rid, nxtP, nxtAll, plannerKey)
            if nargin < 5, plannerKey = 'independent'; end
            if strcmp(plannerKey, 'prioritized')
                canMove = true; wFor = 0; isCol = false; return;
            end

            canMove = true;
            wFor = 0;
            isCol = false;

            myNxt = round(nxtP);
            myCur = round(app.Robots(rid).currentPos);
            
            % Conflict dropdown degerini oku
            conflictMode = app.getConflictMode();

            for j = 1:length(app.Robots)
                if rid == j, continue; end
                oCur = round(app.Robots(j).currentPos);
                oNxt = round(nxtAll{j});

                % Ayni hucreye gitme cakmasi
                if isequal(myNxt, oNxt)
                    switch conflictMode
                        case 'wait_priority'
                            % Dusuk ID gecis oncelikli
                            if rid > j
                                canMove = false; wFor = j; isCol = true; return;
                            end
                        case 'token_passing'
                            % Token sirasi: sadece CurrentStep mod nRob == rid-1 ise hareket et
                            tokenHolder = mod(app.CurrentStep, length(app.Robots)) + 1;
                            if rid ~= tokenHolder
                                canMove = false; wFor = j; isCol = true; return;
                            end
                        case 'reservation'
                            % Reservation: onceden planlanmis robot oncelikli
                            if isfield(app.Robots(j), 'pathIndex') && app.Robots(j).pathIndex > 1
                                canMove = false; wFor = j; isCol = true; return;
                            elseif rid > j
                                canMove = false; wFor = j; isCol = true; return;
                            end
                        otherwise % reactive
                            % Reactive: dusuk ID gecer, yuksek bekler veya replan
                            if rid > j
                                canMove = false; wFor = j; isCol = true; return;
                            end
                    end
                end

                % Yer degistirme cakmasi (swap conflict)
                if isequal(myNxt, oCur)
                    if isequal(oNxt, myCur)
                        % Karsilikli swap - ID'ye gore coz
                        switch conflictMode
                            case 'token_passing'
                                tokenHolder = mod(app.CurrentStep, length(app.Robots)) + 1;
                                if rid ~= tokenHolder
                                    canMove = false; wFor = j; isCol = true; return;
                                end
                            otherwise
                                if rid > j
                                    canMove = false; wFor = j; isCol = true; return;
                                end
                        end
                    else
                        % Diger robot yerinde kaliyor, ben oraya gidemem
                        canMove = false; wFor = j; return;
                    end
                end
            end
        end
        
        function mode = getConflictMode(app)
            % Conflict dropdown degerini key'e cevirir
            val = app.ConflictDrop.Value;
            if contains(val, 'Wait') || contains(val, 'Priority')
                mode = 'wait_priority';
            elseif contains(val, 'Token')
                mode = 'token_passing';
            elseif contains(val, 'Reservation')
                mode = 'reservation';
            else
                mode = 'reactive';
            end
        end

        function createRandomMap(app)
            % Rastgele geometrik engeller iceren harita olusturur
            app.MapSize = app.MapSizeSpinner.Value;
            app.ObstacleDensity = app.DensitySlider.Value / 100;
            app.Seed = app.SeedField.Value;
            rng(app.Seed);

            n = app.MapSize;
            matris = false(n, n);
            hedefOran = app.ObstacleDensity;
            mevcutOran = 0;
            maxDeneme = 900;
            deneme = 0;

            % 1=kucuk dikdortgen, 2=buyuk dikdortgen, 3=L-sekli, 4=U-sekli, 5=dar gecit, 6=elips
            sekilOlasilik = [0.25, 0.15, 0.20, 0.12, 0.18, 0.10];
            kumulatif = cumsum(sekilOlasilik);

            while mevcutOran < hedefOran && deneme < maxDeneme
                deneme = deneme + 1;
                if deneme >= maxDeneme
                    % while condition zaten bunu yapiyordu ama net olsun istedim.
                    break;
                end
                r = rand();
                if r < kumulatif(1)
                    sekilTipi = 1;
                elseif r < kumulatif(2)
                    sekilTipi = 2;
                elseif r < kumulatif(3)
                    sekilTipi = 3;
                elseif r < kumulatif(4)
                    sekilTipi = 4;
                elseif r < kumulatif(5)
                    sekilTipi = 5;
                else
                    sekilTipi = 6;
                end
                aci = randi([0, 3]) * 90;

                switch sekilTipi
                    case 1
                        % Küçük dikdörtgen blok
                        w = randi([2, min(4, max(2, floor(n/12)))]);
                        h = randi([2, min(4, max(2, floor(n/12)))]);
                        if aci == 90 || aci == 270, tmp = w; w = h; h = tmp; end
                        xmax = n - w - 3; ymax = n - h - 3;
                        if xmax < 4, xmax = 4; end
                        if ymax < 4, ymax = 4; end
                        x = randi([4, xmax]); y = randi([4, ymax]);
                        y2 = min(y+h-1, n); x2 = min(x+w-1, n);
                        matris(y:y2, x:x2) = true;

                    case 2
                        % Büyük dikdortgen blok
                        w = randi([5, min(10, max(5, floor(n/5)))]);
                        h = randi([5, min(10, max(5, floor(n/5)))]);
                        if aci == 90 || aci == 270, tmp = w; w = h; h = tmp; end
                        xmax = max(5, n - w - 3); ymax = max(5, n - h - 3);
                        x = randi([4, xmax]); y = randi([4, ymax]);
                        y2 = min(y+h-1, n); x2 = min(x+w-1, n);
                        matris(y:y2, x:x2) = true;

                    case 3
                        % L-sekli engel
                        w = randi([4, min(8, max(4, floor(n/7)))]);
                        h = randi([4, min(8, max(4, floor(n/7)))]);
                        thk = randi([1, 2]);
                        xmax = max(5, n - w - 3); ymax = max(5, n - h - 3);
                        x = randi([4, xmax]); y = randi([4, ymax]);
                        switch mod(floor(aci/90), 4)
                            case 0
                                % Sol dikey + alt yatay
                                y2 = min(y+h-1, n); x2t = min(x+thk-1, n);
                                matris(y:y2, x:x2t) = true;
                                yb1 = min(y+h-thk, n); yb2 = min(y+h-1, n); xb2 = min(x+w-1, n);
                                matris(yb1:yb2, x:xb2) = true;
                            case 1
                                % Ust yatay + sol dikey
                                yt2 = min(y+thk-1, n); xt2 = min(x+w-1, n);
                                matris(y:yt2, x:xt2) = true;
                                yv2 = min(y+h-1, n); xvt = min(x+thk-1, n);
                                matris(y:yv2, x:xvt) = true;
                            case 2
                                % Sag dikey + ust yatay
                                yv2 = min(y+h-1, n); xr1 = min(x+w-thk, n); xr2 = min(x+w-1, n);
                                matris(y:yv2, xr1:xr2) = true;
                                yt2 = min(y+thk-1, n); xt2 = min(x+w-1, n);
                                matris(y:yt2, x:xt2) = true;
                            case 3
                                % Alt yatay + sag dikey
                                yb1 = min(y+h-thk, n); yb2 = min(y+h-1, n); xb2 = min(x+w-1, n);
                                matris(yb1:yb2, x:xb2) = true;
                                yv2 = min(y+h-1, n); xr1 = min(x+w-thk, n); xr2 = min(x+w-1, n);
                                matris(y:yv2, xr1:xr2) = true;
                        end

                    case 4
                        % U-sekli engel
                        w = randi([5, min(10, max(5, floor(n/5)))]);
                        h = randi([4, min(8, max(4, floor(n/6)))]);
                        thk = randi([1, 2]);
                        xmax = max(5, n - w - 3); ymax = max(5, n - h - 3);
                        x = randi([4, xmax]); y = randi([4, ymax]);

                        yv2 = min(y+h-1, n); xlt = min(x+thk-1, n);
                        matris(y:yv2, x:xlt) = true;

                        xr1 = min(x+w-thk, n); xr2 = min(x+w-1, n);
                        matris(y:yv2, xr1:xr2) = true;

                        yb1 = min(y+h-thk, n); yb2 = min(y+h-1, n); xb2 = min(x+w-1, n);
                        matris(yb1:yb2, x:xb2) = true;

                    case 5
                        % Dar gecit: paralel iki duvar arasi 1-2 birim bosluk
                        len = randi([6, min(14, max(6, floor(n/3)))]);
                        gap = randi([1, 2]);
                        xmax = max(5, n - len - 3); ymax = max(5, n - gap - 6);
                        x = randi([4, xmax]); y = randi([4, ymax]);
                        if aci == 0 || aci == 180

                            x2 = min(x + len - 1, n);
                            matris(y, x:x2) = true;
                            y2 = min(y + gap + 1, n);
                            matris(y2, x:x2) = true;
                        else

                            y2 = min(y + len - 1, n);
                            matris(y:y2, x) = true;
                            x2 = min(x + gap + 1, n);
                            matris(y:y2, x2) = true;
                        end

                    case 6
                        % Elips/oval engel
                        rx = randi([2, min(5, max(2, floor(n/10)))]);
                        ry = randi([2, min(5, max(2, floor(n/10)))]);
                        cxMax = n - rx - 3; cyMax = n - ry - 3;
                        if cxMax < rx + 4, cxMax = rx + 4; end
                        if cyMax < ry + 4, cyMax = ry + 4; end
                        cx = randi([rx + 4, cxMax]);
                        cy = randi([ry + 4, cyMax]);
                        for ii = max(1, cy - ry):min(n, cy + ry)
                            for jj = max(1, cx - rx):min(n, cx + rx)
                                if ((ii - cy)/ry)^2 + ((jj - cx)/rx)^2 <= 1
                                    matris(ii, jj) = true;
                                end
                            end
                        end
                end
                mevcutOran = sum(matris(:)) / (n * n);
            end

            % Kenarlari acik birakiyoruz ki robotlar koseye sikismasin
            matris(1:3, :) = false;
            matris(end-2:end, :) = false;
            matris(:, 1:3) = false;
            matris(:, end-2:end) = false;

            app.Map = binaryOccupancyMap(n, n, 1);
            for ii = 1:n
                for jj = 1:n
                    if matris(ii, jj), setOccupancy(app.Map, [jj, ii], 1); end
                end
            end

            app.clearDeadEnds();
            app.ensureConnectivity();

            if ~app.validateMap()
                app.Seed = app.Seed + 1;
                app.SeedField.Value = app.Seed;
                app.createRandomMap();
                return;
            end

            app.Robots = struct([]);
            app.renderMap();
            occ = occupancyMatrix(app.Map);
            app.log(sprintf('map ok, %.0f%%', sum(occ(:))/(n*n)*100));
        end

        function createCorridorMap(app)
            % Deadlock testi icin özel koridor haritası
            app.MapSize = app.MapSizeSpinner.Value;
            app.Seed = app.SeedField.Value;
            rng(app.Seed);
            n = app.MapSize;

            app.Map = binaryOccupancyMap(n, n, 1);

            occ = ones(n, n);

            ortaY = floor(n / 2);
            for x = 4:n-3
                occ(ortaY, x) = 0;
                occ(ortaY + 1, x) = 0;
            end

            % Sol ve sag tarafta genis alanlar (robotlarin toplanacagi yerler)
            solAlanX = 4:floor(n/5);
            sagAlanX = floor(4*n/5):n-3;
            for y = floor(n/4):floor(3*n/4)
                for x = solAlanX
                    occ(y, x) = 0;
                end
                for x = sagAlanX
                    occ(y, x) = 0;
                end
            end


            % Bu noktalarda sadece 1 robot gecebilir - deadlock oluşur
            darbogazSayisi = 2 + randi([1, 3]);
            aralik = floor((n - 10) / (darbogazSayisi + 1));
            for k = 1:darbogazSayisi
                dbX = floor(n/5) + k * aralik + randi([-2, 2]);
                dbX = max(floor(n/5) + 3, min(floor(4*n/5) - 3, dbX));
                % Darbogazda ust satiri kapat, sadece alt satir acik
                occ(ortaY, dbX) = 1;
                occ(ortaY, dbX - 1) = 1;
                occ(ortaY, dbX + 1) = 1;
            end

            % Dikey baglanti koridorlari (dar, 1-2 birim)
            dikeyKonum1 = floor(n/4) + randi([0, 3]);
            dikeyKonum2 = floor(3*n/4) - randi([0, 3]);
            for y = 4:ortaY-1
                occ(y, dikeyKonum1) = 0;
                if rand() > 0.3, occ(y, dikeyKonum1 + 1) = 0; end
            end
            for y = ortaY+2:n-3
                occ(y, dikeyKonum2) = 0;
                if rand() > 0.3, occ(y, dikeyKonum2 - 1) = 0; end
            end

            % Ust ve alt yatay koridorlar (daha dar)
            ustY = floor(n/4);
            altY = floor(3*n/4);
            for x = dikeyKonum1:dikeyKonum2
                if rand() > 0.15, occ(ustY, x) = 0; end
                if rand() > 0.15, occ(altY, x) = 0; end
            end

            % Rastgele ek engeller (koridor icine serpistir)
            ekEngelSayisi = randi([3, 8]);
            for k = 1:ekEngelSayisi
                ex = randi([floor(n/4), floor(3*n/4)]);
                ey = ortaY + randi([-1, 1]);
                if occ(ey, ex) == 0
                    occ(ey, ex) = 1;
                end
            end

            % Kenarlari açık tut (spawn alani)
            occ(1:3, :) = 0; occ(end-2:end, :) = 0;
            occ(:, 1:3) = 0; occ(:, end-2:end) = 0;

            % Haritayi guncelle
            for ii = 1:n
                for jj = 1:n
                    setOccupancy(app.Map, [jj, ii], occ(ii, jj));
                end
            end

            app.Robots = struct([]);
            app.renderMap();
            engelOran = sum(occ(:)) / (n * n) * 100;
            app.log(sprintf('koridor: engel=%.0f%%, db=%d', engelOran, darbogazSayisi));
        end


        function isValid = validateMap(app)

            n = app.MapSize;
            occ = occupancyMatrix(app.Map);
            free = occ == 0;
            bosOran = sum(free(:)) / (n * n);
            if bosOran < 0.4
                isValid = false;
                return;
            end
            [~, enBuyukBoyut] = app.getLargestComponent();
            toplamBos = sum(free(:));
            if enBuyukBoyut < toplamBos * 0.8
                isValid = false;
                return;
            end
            isValid = true;
        end

        function [bolge, maxBoyut] = getLargestComponent(app)
            % BFS ile en buyuk bagli bos bolgeyi bulur
            n = app.MapSize;
            occ = occupancyMatrix(app.Map);
            free = occ == 0;
            ziyaret = false(n, n);
            bolge = [];
            maxBoyut = 0;
            yonler = [0 1; 0 -1; 1 0; -1 0];

            for ii = 1:n
                for jj = 1:n
                    if free(ii, jj) && ~ziyaret(ii, jj)
                        kuyruk = [ii, jj];
                        ziyaret(ii, jj) = true;
                        hucreler = [ii, jj];
                        while ~isempty(kuyruk)
                            mevcut = kuyruk(1, :);
                            kuyruk(1, :) = [];
                            for k = 1:4
                                yi = mevcut(1) + yonler(k, 1);
                                xi = mevcut(2) + yonler(k, 2);
                                if yi >= 1 && yi <= n && xi >= 1 && xi <= n
                                    if free(yi, xi) && ~ziyaret(yi, xi)
                                        ziyaret(yi, xi) = true;
                                        kuyruk = [kuyruk; yi, xi]; %#ok<AGROW>
                                        hucreler = [hucreler; yi, xi]; %#ok<AGROW>
                                    end
                                end
                            end
                        end
                        if size(hucreler, 1) > maxBoyut
                            maxBoyut = size(hucreler, 1);
                            bolge = hucreler;
                        end
                    end
                end
            end
        end

        function ensureConnectivity(app)
            % Haritadaki boş bölgeleri engel ile doldurur

            n = app.MapSize;
            occ = occupancyMatrix(app.Map);
            free = occ == 0;
            ziyaret = false(n, n);
            bolgeler = {};
            yonler = [0 1; 0 -1; 1 0; -1 0];

            for ii = 1:n
                for jj = 1:n
                    if free(ii, jj) && ~ziyaret(ii, jj)
                        bolge = [];
                        kuyruk = [ii, jj];
                        ziyaret(ii, jj) = true;
                        bolge = [bolge; ii, jj]; %#ok<AGROW>
                        while ~isempty(kuyruk)
                            mevcut = kuyruk(1, :);
                            kuyruk(1, :) = [];
                            for k = 1:4
                                yi = mevcut(1) + yonler(k, 1);
                                xi = mevcut(2) + yonler(k, 2);
                                if yi >= 1 && yi <= n && xi >= 1 && xi <= n
                                    if free(yi, xi) && ~ziyaret(yi, xi)
                                        ziyaret(yi, xi) = true;
                                        kuyruk = [kuyruk; yi, xi]; %#ok<AGROW>
                                        bolge = [bolge; yi, xi]; %#ok<AGROW>
                                    end
                                end
                            end
                        end
                        bolgeler{end + 1} = bolge; %#ok<AGROW>
                    end
                end
            end

            % Birden fazla bölge varsa, en buyuk olani haric kalanları engel yap
            if length(bolgeler) > 1
                maxBoyut = 0;
                for cc = 1:length(bolgeler)
                    if size(bolgeler{cc}, 1) > maxBoyut
                        maxBoyut = size(bolgeler{cc}, 1);
                    end
                end
                for cc = 1:length(bolgeler)
                    if size(bolgeler{cc}, 1) ~= maxBoyut
                        for kk = 1:size(bolgeler{cc}, 1)
                            setOccupancy(app.Map, [bolgeler{cc}(kk, 2), bolgeler{cc}(kk, 1)], 1);
                        end
                    end
                end
            end
        end

        function createMazeMap(app)
            app.MapSize = app.MapSizeSpinner.Value;
            app.Seed = app.SeedField.Value;
            rng(app.Seed);
            boyut = app.MapSize;

            occ = ones(boyut, boyut);
            vis = false(boyut, boyut);
            yigin = [3, 3]; vis(3,3) = true; occ(3,3) = 0;
            dy = [0, 0, -2, 2]; dx = [-2, 2, 0, 0];

            % burasi basit DFS labirent
            while ~isempty(yigin)
                cur = yigin(end, :); yc = cur(1); xc = cur(2);
                komsular = [];
                for d = randperm(4)
                    ny = yc + dy(d); nx = xc + dx(d);
                    if ny >= 3 && ny <= boyut-2 && nx >= 3 && nx <= boyut-2 && ~vis(ny, nx)
                        komsular = [komsular; ny, nx, d]; %#ok<AGROW>
                    end
                end
                if ~isempty(komsular)
                    nxt = komsular(1, :);
                    wy = yc + dy(nxt(3))/2; wx = xc + dx(nxt(3))/2;
                    occ(wy, wx) = 0; occ(nxt(1), nxt(2)) = 0;
                    vis(nxt(1), nxt(2)) = true;
                    yigin = [yigin; nxt(1), nxt(2)]; %#ok<AGROW>
                else
                    yigin(end, :) = [];
                end
            end

            occ(1:2, :) = 0; occ(end-1:end, :) = 0;
            occ(:, 1:2) = 0; occ(:, end-1:end) = 0;
            for kk = 1:floor(boyut*boyut*0.027)
                occ(randi([4,boyut-3]), randi([4,boyut-3])) = 0;
            end

            app.Map = binaryOccupancyMap(boyut, boyut, 1);
            for ii = 1:boyut
                for jj = 1:boyut, setOccupancy(app.Map, [jj, ii], occ(ii, jj)); end
            end
            app.Robots = struct([]);
            app.renderMap();
            app.log(sprintf('maze, %.0f%%', sum(occ(:))/(boyut*boyut)*100));
        end

        function drawWaitForGraph(app)
            ax = app.MapAxes;
            hold(ax, 'on');

            for i = 1:length(app.Robots)
                if isfield(app.Robots(i), 'waitingFor') && app.Robots(i).waitingFor > 0
                    j = app.Robots(i).waitingFor;
                    pos1 = app.Robots(i).currentPos;
                    pos2 = app.Robots(j).currentPos;

                    dx = pos2(1) - pos1(1);
                    dy = pos2(2) - pos1(2);

                    quiver(ax, pos1(1), pos1(2), dx*0.8, dy*0.8, 0, ...
                        'Color', app.C_OK, 'LineWidth', 4, 'MaxHeadSize', 2);
                end
            end
            hold(ax, 'off');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            ax = app.MapAxes;
            cla(ax);
            ax.Color = [0.05 0.06 0.08];
            ax.XColor = 'none';
            ax.YColor = 'none';
            ax.XTick = [];
            ax.YTick = [];
            axis(ax, 'equal');
            xlim(ax, [0.5, app.MapSize+0.5]);
            ylim(ax, [0.5, app.MapSize+0.5]);
            app.Seed = randi([1, 99999]);
            app.SeedField.Value = app.Seed;

            app.log('fleet manager baslatıldı harita oluşturmak için buton tılayın.');
        end

        % Value changed function: MapSizeSpinner
        function MapSizeSpinnerValueChanged(app, event)
            value = app.MapSizeSpinner.Value;
            app.MapSize = value;
            app.log(sprintf('harita boyutu: %dx%d', value, value));

        end

        % Button pushed function: BtnClearLog
        function BtnClearLogButtonPushed(app, event)
            app.LogArea.Value = {''};
        end

        % Value changed function: DensitySlider
        function DensitySliderValueChanged(app, event)
            value = app.DensitySlider.Value;
            app.ObstacleDensity = value / 100;
            app.DensityValue.Text = sprintf('%d%%', round(value));
        end

        % Button pushed function: BtnRandomMap
        function BtnRandomMapButtonPushed(app, event)
            mapType = app.MapTypeDropdown.Value;


            if app.SeedField.Value == 0
                app.Seed = randi([1, 99999]);
                app.SeedField.Value = app.Seed;
            else
                app.Seed = app.SeedField.Value;
            end

            switch mapType
                case 'Rastgele'
                    app.createRandomMap();
                case 'Dar Koridor'
                    app.createCorridorMap();
                case 'Labirent'
                    app.createMazeMap();
            end
        end

        % Button pushed function: BtnPlaceRobots
        function BtnPlaceRobotsButtonPushed(app, event)
            if isempty(app.Map)
                app.log('hata: harita yok');
                return;
            end

            rng(app.Seed);
            haritaBoyutu = app.MapSize;
            app.NumRobots = app.SeedField_2.Value;

            app.RobotColors = lines(app.NumRobots);

            doluluk = occupancyMatrix(app.Map);
            [bosY, bosX] = find(doluluk == 0);
            bosHuc = [bosX, bosY];

            if size(bosHuc, 1) < app.NumRobots * 2
                app.log('hata: alan yok');
                return;
            end

            app.Robots = struct('id', {}, 'start', {}, 'goal', {}, 'path', {}, 'currentPos', {}, 'status', {});

            kullanilan = [];
            kose = sqrt(2) * haritaBoyutu;

            for i = 1:app.NumRobots
                baslaOk = false;
                deneme = 0;
                while ~baslaOk && deneme < 100
                    deneme = deneme + 1;
                    idx = randi(size(bosHuc, 1));
                    basPos = bosHuc(idx, :);
                    if isempty(kullanilan) || ~any(all(kullanilan == basPos, 2))
                        baslaOk = true;
                    end
                end
                kullanilan = [kullanilan; basPos];

                mesafeTip = mod(i-1, 3) + 1;
                switch mesafeTip
                    case 1
                        minUzak = 0.10 * kose;
                        maxUzak = 0.20 * kose;
                    case 2
                        minUzak = 0.30 * kose;
                        maxUzak = 0.50 * kose;
                    case 3
                        minUzak = 0.60 * kose;
                        maxUzak = kose;
                end


                hedefOk = false;
                deneme = 0;
                hedefPos = basPos;
                while ~hedefOk && deneme < 200
                    deneme = deneme + 1;
                    idx = randi(size(bosHuc, 1));
                    aday = bosHuc(idx, :);
                    uzaklik = norm(aday - basPos);
                    if uzaklik >= minUzak && uzaklik <= maxUzak
                        if isempty(kullanilan) || ~any(all(kullanilan == aday, 2))
                            hedefPos = aday;
                            hedefOk = true;
                        end
                    end
                end
                if ~hedefOk

                    for j = 1:size(bosHuc, 1)
                        if ~any(all(kullanilan == bosHuc(j,:), 2))
                            hedefPos = bosHuc(j, :);
                            break;
                        end
                    end
                end
                kullanilan = [kullanilan; hedefPos];

                app.Robots(i).id = i;
                app.Robots(i).start = basPos;
                app.Robots(i).goal = hedefPos;
                app.Robots(i).currentPos = basPos;
                app.Robots(i).path = [];
                app.Robots(i).status = 'Bekliyor';
            end

            app.renderMap();
            app.updateRobotTable();
            app.log(sprintf('%d rob ok', app.NumRobots));
        end

        % Button pushed function: BtnClearRobots
        function BtnClearRobotsButtonPushed(app, event)
            app.Robots = struct([]);
            app.renderMap();
            app.RobotTable.Data = {};
            app.log('Robotlar temizlendi.');
        end

        % Button pushed function: PrimaryButton
        function PrimaryButtonPushed(app, event)

            if app.IsRunning
                app.IsRunning = false;
                app.RunStateBadge.Text = '● STOPPED';
                app.RunStateBadge.FontColor = app.C_DIM;
                app.PrimaryButton.Text = 'Simulasyonu Baslat';
                app.PrimaryButton.BackgroundColor = app.C_ACCENT;
                app.log('Simulasyon kullanici tarafindan durduruldu.');
                return;
            end

            if isempty(app.Robots)
                app.log('HATA: Once robotlari yerlestirin!');
                return;
            end


            plannerType = app.GlobalPlannerDrop.Value;
            app.log(sprintf('Planlayici: %s', plannerType));


            app.planPaths();

            app.updateRobotTable();
            app.RunStateBadge.Text = '● RUNNING';
            app.RunStateBadge.FontColor = app.C_OK;
            app.PrimaryButton.Text = 'Simulasyonu Durdur';
            app.PrimaryButton.BackgroundColor = app.C_BAD;


            app.runSimulation();

            % Simülasyon bitince butonu eski haline getir
            app.PrimaryButton.Text = 'Simulasyonu Baslat';
            app.PrimaryButton.BackgroundColor = app.C_ACCENT;

        end

        % Value changed function: SeedField
        function SeedFieldValueChanged(app, event)
            value = app.SeedField.Value;
            app.Seed = value;
            app.log(sprintf('Seed degeri: %d', value));
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)

            if isempty(app.Robots)
                app.log('hata: robot yok');
                return;
            end

            % Robot secimi icin dialog goster
            robotList = cell(1, length(app.Robots));
            for i = 1:length(app.Robots)
                robotList{i} = sprintf('Robot %d', i);
            end

            [selectedIdx, ok] = listdlg('ListString', robotList, ...
                'SelectionMode', 'single', ...
                'Name', 'Sec', ...
                'PromptString', 'Robot:', ...
                'ListSize', [200 150]);

            if ~ok || isempty(selectedIdx)
                return;
            end

            selectedRobotIdx = selectedIdx;
            robot = app.Robots(selectedRobotIdx);

            % Yol verisi kontrol
            if isempty(robot.history) || size(robot.history, 1) < 2
                if isempty(robot.path) || size(robot.path, 1) < 2
                    return;
                end
                pathData = robot.path;
            else
                pathData = robot.history;
            end

            nSteps = size(pathData, 1);
            t = 0:(nSteps-1);

            v = zeros(nSteps, 1);
            w = zeros(nSteps, 1);
            theta = zeros(nSteps, 1);

            for i = 2:nSteps
                dx = pathData(i, 1) - pathData(i-1, 1);
                dy = pathData(i, 2) - pathData(i-1, 2);
                v(i) = sqrt(dx^2 + dy^2);
                theta(i) = atan2(dy, dx);
            end

            for i = 2:nSteps
                dTheta = theta(i) - theta(i-1);
                while dTheta > pi, dTheta = dTheta - 2*pi; end
                while dTheta < -pi, dTheta = dTheta + 2*pi; end
                w(i) = dTheta;
            end

            L = 0.5;
            vL = v - (L/2) * w;
            vR = v + (L/2) * w;


            figure('Name', sprintf('Robot %d', selectedRobotIdx), ...
                'Position', [100 100 900 700], 'Color', [0.95 0.95 0.95]);

            ax1 = subplot(3, 1, 1);
            set(ax1, 'Color', 'w');
            plot(t, v, 'b-', 'LineWidth', 2);
            title(ax1, sprintf('Robot %d - v (m/s)', selectedRobotIdx), 'FontSize', 12, 'FontWeight', 'bold', 'Color', 'k');
            xlabel(ax1, 't (s)', 'FontSize', 10, 'Color', 'k');
            ylabel(ax1, 'v', 'FontSize', 10, 'Color', 'k');
            set(ax1, 'XColor', 'k', 'YColor', 'k', 'GridColor', [0.5 0.5 0.5], 'GridAlpha', 0.5);
            grid(ax1, 'on');
            if max(t) > 0, xlim(ax1, [0 max(t)]); end

            ax2 = subplot(3, 1, 2);
            set(ax2, 'Color', 'w');
            plot(t, w, 'r-', 'LineWidth', 2);
            title(ax2, sprintf('Robot %d - w (rad/s)', selectedRobotIdx), 'FontSize', 12, 'FontWeight', 'bold', 'Color', 'k');
            xlabel(ax2, 't (s)', 'FontSize', 10, 'Color', 'k');
            ylabel(ax2, 'w', 'FontSize', 10, 'Color', 'k');
            set(ax2, 'XColor', 'k', 'YColor', 'k', 'GridColor', [0.5 0.5 0.5], 'GridAlpha', 0.5);
            grid(ax2, 'on');
            if max(t) > 0, xlim(ax2, [0 max(t)]); end

            ax3 = subplot(3, 1, 3);
            set(ax3, 'Color', 'black');
            hold(ax3, 'on');
            plot(ax3, t, vL, 'g-', 'LineWidth', 2, 'DisplayName', 'Sol');
            plot(ax3, t, vR, 'm-', 'LineWidth', 2, 'DisplayName', 'Sag');
            hold(ax3, 'off');
            title(ax3, sprintf('Robot %d - Tekerlek', selectedRobotIdx), 'FontSize', 12, 'FontWeight', 'bold', 'Color', 'k');
            xlabel(ax3, 't (s)', 'FontSize', 10, 'Color', 'k');
            ylabel(ax3, 'v', 'FontSize', 10, 'Color', 'k');
            set(ax3, 'XColor', 'k', 'YColor', 'k', 'GridColor', [0.5 0.5 0.5], 'GridAlpha', 0.5);
            legend(ax3, 'Location', 'northeast', 'TextColor', 'w');
            grid(ax3, 'on');
            if max(t) > 0, xlim(ax3, [0 max(t)]);
            end
        end

        % Value changed function: MapTypeDropdown
        function MapTypeDropdownValueChanged(app, event)
            value = app.MapTypeDropdown.Value;
            app.log(sprintf('harita tipi sçildi: %s', value));
            switch value
                case 'Rastgele'
                    app.createRandomMap();
                case 'Dar Koridor'
                    app.createCorridorMap();
                case 'Labirent'
                    app.createMazeMap();
            end
        end

        % Value changed function: GlobalPlannerDrop
        function GlobalPlannerDropValueChanged(app, event)
            value = app.GlobalPlannerDrop.Value;
            switch value
                case 'A* (Standard Optimal)'
                    app.InfoBox.Value = {'Independent A*: statik engel','catisma runtime oncelik ile cozulecek'};
                case 'CBS (Multi-Agent)'
                    app.InfoBox.Value = {'Space-Time A*: rezervasyon','(x,y,t)+bekleme hamlesi, ID sirasina gore'};
                case 'WHCA* (Fast)'
                    app.InfoBox.Value = {'Windowed A*: 10 adimlik pencere','her pencere sonunda yeniden planlar'};
                case 'Prioritized Planning'
                    app.InfoBox.Value = {'Space-Time A* (Priority)','ID sirasi ile zamanli rezervasyon'};
            end

            if ~isempty(app.Robots)
                app.planPaths();
                app.renderMap();
                drawnow;
            end
        end

        % Value changed function: ConflictDrop
        function ConflictDropValueChanged(app, event)
             value = app.ConflictDrop.Value;
            if contains(value, 'Reactive')
                app.InfoBox.Value = {'Reactive Re-planning:', ...
                    'Cakisma aninda dusuk ID gecis oncelikli.', ...
                    'Yuksek ID bekler, deadlock olursa replan yapar.'};
            elseif contains(value, 'Wait') || contains(value, 'Priority')
                app.InfoBox.Value = {'Wait & Priority:', ...
                    'Ayni hucreye gitmek isteyen robotlardan', ...
                    'dusuk ID her zaman onceliklidir.', ...
                    'Diger robot bir adim bekler.'};
            elseif contains(value, 'Token')
                app.InfoBox.Value = {'Token Passing:', ...
                    'Her adimda sadece 1 robot hareket eder.', ...
                    'Token sirasi: step mod N ile belirlenir.', ...
                    'Cakisma olmaz ama yavas ilerler.'};
            elseif contains(value, 'Reservation')
                app.InfoBox.Value = {'Reservation Table:', ...
                    'Yolda ilerlemis robot (pathIndex>1)', ...
                    'oncelik kazanir. Henuz basamamis', ...
                    'robot bekler. Space-Time uyumlu.'};
            end
            app.log(sprintf('Cakisma modu: %s', app.getConflictMode()));
        end

        % Value changed function: HeuristicDrop
        function HeuristicDropValueChanged(app, event)
            value = app.HeuristicDrop.Value;
            switch value
                case 'Manhattan'
                    app.InfoBox.Value = {'Manhattan: |dx|+|dy|','4 yönlü grid icin'};
                case 'Euclidean'
                    app.InfoBox.Value = {'Euclidean: sqrt','capraz hareket icin'};
                case 'Chebyshev'
                    app.InfoBox.Value = {'Chebyshev: max','satranc mesafesi'};
            end

            if ~isempty(app.Robots)
                app.planPaths();
                app.renderMap();
                drawnow;
            end
        end

        % Value changed function: InfoBox
        function InfoBoxValueChanged(app, event)

        end

        % Value changed function: ShowPlannedPath
        function ShowPlannedPathValueChanged(app, event)
            value = app.ShowPlannedPath.Value;

            if ~isempty(app.Map)
                app.renderMap();
            end
            if value
                app.log('planlanan yollar gösteriliyor');
            else
                app.log('planlanan yollar gizlendi');
            end

        end

        % Value changed function: ShowExecutedPath
        function ShowExecutedPathValueChanged(app, event)
            value = app.ShowExecutedPath.Value;

            if ~isempty(app.Map)
                app.renderMap();
            end
            if value
                app.log('yürütülen yollar gösteriliyor');
            else
                app.log('yürütülen yollar gizlendi');
            end

        end

        % Value changed function: ShowWaitForGraph
        function ShowWaitForGraphValueChanged(app, event)
            value = app.ShowWaitForGraph.Value;

            if ~isempty(app.Map)
                app.renderMap();
            end
            if value
                app.log('wait-for graph gosteriliyor');
            else
                app.log('wait-for graph gizlendi');
            end
        end

        % Value changed function: ShowGoals
        function ShowGoalsValueChanged(app, event)
            value = app.ShowGoals.Value;

            if ~isempty(app.Map)
                app.renderMap();
            end
            if value
                app.log('hedefler gösteriliyor');
            else
                app.log('hedefler gizlendi');
            end

        end

        % Value changed function: ShowIDs
        function ShowIDsValueChanged(app, event)
            value = app.ShowIDs.Value;

            if ~isempty(app.Map)
                app.renderMap();
            end
            if value
                app.log('robot idleri gosteriliyor');
            else
                app.log('robot idleri gizlendi');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Fleet Manager (UI only)';
            app.RootGrid = uigridlayout(app.UIFigure);
            app.MainGrid = uigridlayout(app.RootGrid);
            app.RightPanel = uipanel(app.MainGrid);
            app.RightGrid = uigridlayout(app.RightPanel);
            app.BottomTabs = uitabgroup(app.RightGrid);
            app.LogTab = uitab(app.BottomTabs);
            app.LogTab.Title = 'Log';
            app.LogGrid = uigridlayout(app.LogTab);
            app.BtnClearLog = uibutton(app.LogGrid, 'push');
            app.BtnClearLog.ButtonPushedFcn = createCallbackFcn(app, @BtnClearLogButtonPushed, true);
            app.BtnClearLog.Text = 'Log Temizle';
            app.LogArea = uitextarea(app.LogGrid);
            app.LogArea.Editable = 'off';
            app.RobotsTab = uitab(app.BottomTabs);
            app.RobotsTab.Title = 'Robot Listesi';
            app.RobotsGrid = uigridlayout(app.RobotsTab);
            app.RobotTable = uitable(app.RobotsGrid);
            app.RobotTable.ColumnName = {'ID'; 'Pozisyon'; 'Hedef'; 'Durum'};
            app.RobotTable.RowName = {};
            app.MapHost = uipanel(app.RightGrid);
            app.MapAxes = uiaxes(app.MapHost);
            app.MapAxes.XTick = [];
            app.MapAxes.YTick = [];
            app.CardsRow = uigridlayout(app.RightGrid);
            app.CardDone = uipanel(app.CardsRow);
            app.CardDoneGrid = uigridlayout(app.CardDone);
            app.CardDoneValue = uilabel(app.CardDoneGrid);
            app.CardDoneValue.Text = '0/0';
            app.CardDoneTitle = uilabel(app.CardDoneGrid);
            app.CardDoneTitle.Text = 'TAMAMLANAN';
            app.CardDL = uipanel(app.CardsRow);
            app.CardDLGrid = uigridlayout(app.CardDL);
            app.CardDLValue = uilabel(app.CardDLGrid);
            app.CardDLValue.Text = '0';
            app.CardDLTitle = uilabel(app.CardDLGrid);
            app.CardDLTitle.Text = 'DEADLOCK / CARPISMA';
            app.CardTime = uipanel(app.CardsRow);
            app.CardTimeGrid = uigridlayout(app.CardsRow);
            app.CardTimeValue = uilabel(app.CardTimeGrid);
            app.CardTimeValue.Text = '0.0s';
            app.CardTimeTitle = uilabel(app.CardTimeGrid);
            app.CardTimeTitle.Text = 'SIMULASYON SURESI';
            app.LeftPanel = uipanel(app.MainGrid);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            app.SecondaryRow = uigridlayout(app.LeftGrid);
            app.LegendGrid = uigridlayout(app.SecondaryRow);
            app.LegendWait = uilabel(app.LegendGrid);
            app.LegendWait.Text = '↺ Wait-For (Kilit)';
            app.LegendHedef = uilabel(app.LegendGrid);
            app.LegendHedef.Text = '◆ Hedef';
            app.LegendRobot = uilabel(app.LegendGrid);
            app.LegendRobot.Text = '● Robot';
            app.LegendEngel = uilabel(app.LegendGrid);
            app.LegendEngel.Text = '■ Engel';
            app.LeftTabs = uitabgroup(app.LeftGrid);
            app.TabMap = uitab(app.LeftTabs);
            app.TabMap.Title = 'Harita';
            app.MapTabGrid = uigridlayout(app.TabMap);
            app.MapTypeLabel = uilabel(app.MapTabGrid);
            app.MapTypeLabel.Text = 'HARITA TIPI';
            app.MapTypeDropdown = uidropdown(app.MapTabGrid);
            app.MapTypeDropdown.Items = {'Rastgele', 'Dar Koridor', 'Labirent'};
            app.MapTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @MapTypeDropdownValueChanged, true);
            app.MapTypeDropdown.Value = 'Rastgele';
            app.MapSizeLabel = uilabel(app.MapTabGrid);
            app.MapSizeLabel.Text = 'Boyut';
            app.SeedLabel = uilabel(app.MapTabGrid);
            app.SeedLabel.Text = 'Seed';
            app.MapSizeSpinner = uispinner(app.MapTabGrid);
            app.MapSizeSpinner.Limits = [20 120];
            app.MapSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MapSizeSpinnerValueChanged, true);
            app.MapSizeSpinner.Value = 40;
            app.SeedField = uieditfield(app.MapTabGrid, 'numeric');
            app.SeedField.ValueChangedFcn = createCallbackFcn(app, @SeedFieldValueChanged, true);
            app.SeedField.Value = 42;
            app.DensityLabel = uilabel(app.MapTabGrid);
            app.DensityLabel.Text = 'Engel Yogunlugu';
            app.DensityValue = uilabel(app.MapTabGrid);
            app.DensityValue.Text = '20%';
            app.DensitySlider = uislider(app.MapTabGrid);
            app.DensitySlider.Limits = [5 45];
            app.DensitySlider.MajorTicks = [];
            app.DensitySlider.ValueChangedFcn = createCallbackFcn(app, @DensitySliderValueChanged, true);
            app.DensitySlider.MinorTicks = [5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45];
            app.DensitySlider.Value = 20;
            app.BtnRandomMap = uibutton(app.MapTabGrid, 'push');
            app.BtnRandomMap.ButtonPushedFcn = createCallbackFcn(app, @BtnRandomMapButtonPushed, true);
            app.BtnRandomMap.Text = 'Random Map Olustur';
            app.DensityLabel_2 = uilabel(app.MapTabGrid);
            app.DensityLabel_2.Text = 'Robot sayısı ';
            app.SeedField_2 = uispinner(app.MapTabGrid);
            app.SeedField_2.Limits = [-100 100];
            app.SeedField_2.Value = 10;
            app.BtnPlaceRobots = uibutton(app.MapTabGrid, 'push');
            app.BtnPlaceRobots.ButtonPushedFcn = createCallbackFcn(app, @BtnPlaceRobotsButtonPushed, true);
            app.BtnPlaceRobots.Text = 'Robotlari Yerlestir';
            app.BtnClearRobots = uibutton(app.MapTabGrid, 'push');
            app.BtnClearRobots.ButtonPushedFcn = createCallbackFcn(app, @BtnClearRobotsButtonPushed, true);
            app.BtnClearRobots.Text = 'Temizle';
            app.PrimaryButton = uibutton(app.MapTabGrid, 'push');
            app.PrimaryButton.ButtonPushedFcn = createCallbackFcn(app, @PrimaryButtonPushed, true);
            app.PrimaryButton.Text = 'Simulasyonu Baslat';
            app.TabPlan = uitab(app.LeftTabs);
            app.TabPlan.Title = 'Planlama';
            app.PlanTabGrid = uigridlayout(app.TabPlan);
            app.InfoBox = uitextarea(app.PlanTabGrid);
            app.InfoBox.ValueChangedFcn = createCallbackFcn(app, @InfoBoxValueChanged, true);
            app.InfoBox.Editable = 'off';
            app.InfoBox.Value = {'Reactive Re-planning:'; 'Carpisma aninda durur, dusuk oncelikli robot bekler veya yeni yol arar.'; ''};
            app.HeuristicDrop = uidropdown(app.PlanTabGrid);
            app.HeuristicDrop.Items = {'Manhattan', 'Euclidean', 'Chebyshev'};
            app.HeuristicDrop.ValueChangedFcn = createCallbackFcn(app, @HeuristicDropValueChanged, true);
            app.HeuristicDrop.Value = 'Manhattan';
            app.HeuristicLabel = uilabel(app.PlanTabGrid);
            app.HeuristicLabel.Text = 'Heuristic';
            app.ConflictDrop = uidropdown(app.PlanTabGrid);
            app.ConflictDrop.Items = {'Reactive Re-planning (Standard)', 'Wait & Priority', 'Token Passing', 'Reservation Table'};
            app.ConflictDrop.ValueChangedFcn = createCallbackFcn(app, @ConflictDropValueChanged, true);
            app.ConflictDrop.Value = 'Reactive Re-planning (Standard)';
            app.ConflictLabel = uilabel(app.PlanTabGrid);
            app.ConflictLabel.Text = '2) CATISMA YONETIMI';
            app.GlobalPlannerDrop = uidropdown(app.PlanTabGrid);
            app.GlobalPlannerDrop.Items = {'A* (Standard Optimal)', 'CBS (Multi-Agent)', 'WHCA* (Fast)', 'Prioritized Planning'};
            app.GlobalPlannerDrop.ValueChangedFcn = createCallbackFcn(app, @GlobalPlannerDropValueChanged, true);
            app.GlobalPlannerDrop.Value = 'A* (Standard Optimal)';
            app.GlobalPlannerLabel = uilabel(app.PlanTabGrid);
            app.GlobalPlannerLabel.Text = '1) GLOBAL ROTA PLANLAYICI';
            app.TabLayers = uitab(app.LeftTabs);
            app.TabLayers.Title = 'Katmanlar';
            app.LayersTabGrid = uigridlayout(app.TabLayers);
            app.ShowIDs = uicheckbox(app.LayersTabGrid);
            app.ShowIDs.ValueChangedFcn = createCallbackFcn(app, @ShowIDsValueChanged, true);
            app.ShowIDs.Text = 'Robot ID';
            app.ShowIDs.Value = true;
            app.ShowGoals = uicheckbox(app.LayersTabGrid);
            app.ShowGoals.ValueChangedFcn = createCallbackFcn(app, @ShowGoalsValueChanged, true);
            app.ShowGoals.Text = 'Hedefleri goster';
            app.ShowGoals.Value = true;
            app.ShowWaitForGraph = uicheckbox(app.LayersTabGrid);
            app.ShowWaitForGraph.ValueChangedFcn = createCallbackFcn(app, @ShowWaitForGraphValueChanged, true);
            app.ShowWaitForGraph.Text = 'Wait-For Graph (Deadlock Oklari)';
            app.ShowExecutedPath = uicheckbox(app.LayersTabGrid);
            app.ShowExecutedPath.ValueChangedFcn = createCallbackFcn(app, @ShowExecutedPathValueChanged, true);
            app.ShowExecutedPath.Text = 'Gerceklesen Yol (Duz Cizgi)';
            app.ShowExecutedPath.Value = true;
            app.ShowPlannedPath = uicheckbox(app.LayersTabGrid);
            app.ShowPlannedPath.ValueChangedFcn = createCallbackFcn(app, @ShowPlannedPathValueChanged, true);
            app.ShowPlannedPath.Text = 'Planlanan Rota (Kesik Cizgi)';
            app.ShowPlannedPath.Value = true;
            app.LayersTitle = uilabel(app.LayersTabGrid);
            app.LayersTitle.Text = 'GORSEL KATMANLAR';
            app.TopBar = uipanel(app.RootGrid);
            app.TopBarGrid = uigridlayout(app.TopBar);
            app.ReportButton = uibutton(app.TopBarGrid, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Analiz Raporu';
            app.RunStateBadge = uilabel(app.TopBarGrid);
            app.RunStateBadge.Text = '● STOPPED';
            app.BrandPanel = uipanel(app.TopBarGrid);
            app.BrandGrid = uigridlayout(app.BrandPanel);
            app.VersionBadge = uilabel(app.BrandGrid);
            app.VersionBadge.Text = 'OzdemirCeng';
            app.BrandSub = uilabel(app.BrandGrid);
            app.BrandSub.Text = 'Multi-Agent Path Finding (MAPF) • Deadlock Resolution';
            app.BrandTitle = uilabel(app.BrandGrid);
            app.BrandTitle.Text = 'FLEET MANAGER';
            app.BrandIcon = uilabel(app.BrandGrid);
            app.BrandIcon.Text = 'FM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FleetManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end